classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure            % UI Figure
        Label          matlab.ui.control.Label     % 2D -> 3D image converter
        Button         matlab.ui.control.Button    % Choose File
        LabelSlider    matlab.ui.control.Label     % Set depth
        Slider         matlab.ui.control.Slider    % [1 25]
        LabelEditField matlab.ui.control.Label     % Loaded File
        EditField      matlab.ui.control.EditField
        Button2        matlab.ui.control.Button    % Convert
    end


    properties (Access = private)
        path = ''; % Description
    end

    methods (Access = private)
        
        function results = anaglyph(app)
            try
            I = imread(app.path);
            depht = app.Slider.Value;
            ymax = size(I,1);
            xmax = size(I,2);
            disp(ymax);
            disp(xmax);
            crop_rect_left = [depht,0,xmax-depht-1,ymax];
            crop_rect_right = [0,0,xmax-depht,ymax];
            
            left_pic = imcrop(I,crop_rect_left);
            right_pic = imcrop(I,crop_rect_right);
            %  subplot(1,2,1), imshow(left_pic);
            %  subplot(1,2,2), imshow(right_pic);
            %J = Anaglyph3D(left_pic,right_pic);
            
            % just empty array
            a = zeros(size(left_pic,1),size(left_pic,2));
            
            
            % Prepare picture for left eye
            red = left_pic(:,:,1);
            left_eye = cat(3,red,a,a);
            
            
            % Prepare picture for right eye
            green = right_pic(:,:,2);
            blue = right_pic(:,:,3);
            right_eye = cat(3,a,green,blue);
            
            
            % adding previously prepared pictures
            J = imadd(left_eye,right_eye);
            
            
            figure, imshow(J);
            catch 
                fig = uifigure;
                uialert(fig, 'File not found', 'Invalid file');
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function ShowImage(app)
            [filename, pathname] = uigetfile({'*.jpg;*.tif;*.png;*.gif'},'File Selector');
            app.path = strcat(pathname,filename);
            app.EditField.Value = filename;
            
        end

        % EditField value changed function
        function fillPathName(app)
            value = app.EditField.Value;
            
        end

        % Slider value changed function
        function SliderValueChanged(app)
            value = app.Slider.Value;
            
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
            anaglyph(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2D -> 3D image converter';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ShowImage);
            app.Button.Text = 'Choose File';
            app.LabelSlider = uilabel(app.UIFigure);
            app.LabelSlider.Text = 'Set depth';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 25];
            app.Slider.MajorTicks = [1 4 7 10 13 16 19 22 25];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged);
            app.Slider.Value = 10;
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Loaded File';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @fillPathName);
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Convert';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end